classdef Simulate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DsimulationUIFigure        matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        SimulateButton             matlab.ui.control.Button
        TrajectoryButtonGroup      matlab.ui.container.ButtonGroup
        StepButton                 matlab.ui.control.RadioButton
        DiamondButton              matlab.ui.control.RadioButton
        CircleButton               matlab.ui.control.RadioButton
        CustomTrajectoryButton     matlab.ui.control.RadioButton
        StopButton                 matlab.ui.control.Button
        CustomTrajectoryPanel      matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        AddButton                  matlab.ui.control.Button
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        SpeedSliderLabel           matlab.ui.control.Label
        SpeedSlider                matlab.ui.control.Slider
        OptimizationButtonGroup    matlab.ui.container.ButtonGroup
        MinimumsnaptrajectoryoptimizationButton  matlab.ui.control.RadioButton
        MinimumaccelerationtrajectoryoptimizationButton  matlab.ui.control.RadioButton
        ClearDataButton            matlab.ui.control.Button
        MassSliderLabel            matlab.ui.control.Label
        MassSlider                 matlab.ui.control.Slider
        GravitySliderLabel         matlab.ui.control.Label
        GravitySlider              matlab.ui.control.Slider
        FmaxSliderLabel            matlab.ui.control.Label
        FmaxSlider                 matlab.ui.control.Slider
        StatePanel                 matlab.ui.container.Panel
        XEditField_2Label          matlab.ui.control.Label
        XEditField_2               matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        YEditField_2               matlab.ui.control.NumericEditField
        ZEditField_2Label          matlab.ui.control.Label
        ZEditField_2               matlab.ui.control.NumericEditField
        VxEditFieldLabel           matlab.ui.control.Label
        VxEditField                matlab.ui.control.NumericEditField
        VyEditFieldLabel           matlab.ui.control.Label
        VyEditField                matlab.ui.control.NumericEditField
        VzEditFieldLabel           matlab.ui.control.Label
        VzEditField                matlab.ui.control.NumericEditField
        phiEditFieldLabel          matlab.ui.control.Label
        phiEditField               matlab.ui.control.NumericEditField
        thetaEditFieldLabel        matlab.ui.control.Label
        thetaEditField             matlab.ui.control.NumericEditField
        psiEditFieldLabel          matlab.ui.control.Label
        psiEditField               matlab.ui.control.NumericEditField
        pEditFieldLabel            matlab.ui.control.Label
        pEditField                 matlab.ui.control.NumericEditField
        qEditFieldLabel            matlab.ui.control.Label
        qEditField                 matlab.ui.control.NumericEditField
        rEditFieldLabel            matlab.ui.control.Label
        rEditField                 matlab.ui.control.NumericEditField
        SimulateVRButton           matlab.ui.control.Button
        AerodynamicaleffectsPanel  matlab.ui.container.Panel
        AxEditFieldLabel           matlab.ui.control.Label
        AxEditField                matlab.ui.control.NumericEditField
        AyEditFieldLabel           matlab.ui.control.Label
        AyEditField                matlab.ui.control.NumericEditField
        AzEditFieldLabel           matlab.ui.control.Label
        AzEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Data
        qn
        params
        FV
        max_iter
        traj
        Optimization
        
    end
    
    methods (Access = private)
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('utils')
            addpath('trajectories')
            app.max_iter  = 5000;      % max iteration
            app.DsimulationUIFigure.Name = '3D Simulation';
            set(app.CustomTrajectoryPanel,'Visible','off')
            axis(app.UIAxes,'equal')
            app.UIAxes.ZGrid = 'on';
            view(app.UIAxes,3);
            app.qn=0;
            app.params = crazyflie();
            value = app.MassSlider.Value;
            app.params.mass = value;
            value = app.FmaxSlider.Value;
            app.params.maxF = value;
            value = app.GravitySlider.Value;
            app.params.grav = value;
            value = app.SpeedSlider.Value;
            app.params.Speed = value;
            value = app.AzEditField.Value;
            app.params.Ax = value;
            value = app.AzEditField.Value;
            app.params.Ay = value;
            value = app.AzEditField.Value;
            app.params.Az = value;
            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            myworld=vrview('vr3d/asbQuadcopterWorld.wrl');
%           mynodes = get(myworld, 'Nodes')
            quadcopter_body = myworld.QuadBody;
            quadcopter_body.translation=[0 0 0];
            
            
            app.qn=app.qn+1;
            set(app.TrajectoryButtonGroup,'Visible','off')
            set(app.CustomTrajectoryPanel,'Visible','off')
            if(app.StepButton.Value)
                app.traj=@step;
            end
            if(app.DiamondButton.Value)
                app.traj=@diamond;
            end
            if(app.CircleButton.Value)
                app.traj=@circle;
            end
            if(app.CustomTrajectoryButton.Value)
                app.traj=@trajectory_generator;
                if app.MinimumsnaptrajectoryoptimizationButton.Value
                    app.Optimization = 7;
                    app.traj([], [], app.Data,app.params.Speed,app.Optimization,3);
                else
                    app.Optimization = 3;
                    app.traj([], [], app.Data,app.params.Speed,app.Optimization,3);
                end
            end
            
            
            try
                
                time_tol = 25;
                quadcolors = lines(app.qn);
                
                starttime = 0;         % start of simulation in seconds
                tstep     = 0.01;      % this determines the time step at which the solution is given
                cstep     = 0.05;      % image capture time interval
                time      = starttime; % current time
                
                des_start = app.traj(0, app.qn,app.Data,app.params.Speed,app.Optimization,3);
                des_stop  = app.traj(inf, app.qn,app.Data,app.params.Speed,app.Optimization,3);
                stop{app.qn}  = des_stop.pos;
                
                x0{app.qn}    = init_state( des_start.pos, 0 ,3);
                x         = x0;        % state
                app.XEditField_2.Value = x{app.qn}(1);
                pos_tol   = 0.01;
                vel_tol   = 0.01;
                for iter = 1:app.max_iter
                    timeint = time:tstep:time+cstep;
                    if iter == 1
                        QP{app.qn} = QuadPlot(app.qn, x0{app.qn}, app.params.arm_length, 0.046, quadcolors(app.qn,:), app.max_iter, app.UIAxes);
                        desired_state = app.traj(time, app.qn, app.Data,app.params.Speed,app.Optimization,3);
                        QP{app.qn}.UpdateQuadPlot(x{app.qn}, [desired_state.pos; desired_state.vel], time);
                    end
                    
                    tt =time + cstep;
                    desired_state = app.traj(tt, app.qn,app.Data,app.params.Speed,app.Optimization,3);  
                    
                    % Run simulation
                    
                    app.FV = @(t,s) (quadEOM(t, s, app.qn, @controller, app.traj, app.params, app.Data,app.params.Speed,app.Optimization,3));
                    [tsave, xsave] = ode45(app.FV , timeint, x{app.qn});
                     
                    x{app.qn}    = xsave(end, :)';
                    
                    app.XEditField_2.Value = xsave(end, 1);
                    app.YEditField_2.Value = xsave(end, 2);
                    app.ZEditField_2.Value = xsave(end, 3);
                    app.VxEditField.Value = xsave(end, 4);
                    app.VyEditField.Value = xsave(end, 5);
                    app.VzEditField.Value = xsave(end, 6);
                    R = QuatToRot(xsave(end,7:10));
                    [phi,theta,psi] = RotToRPY_ZXY(R');
                    app.phiEditField.Value = phi*180/(pi);
                    app.thetaEditField.Value = theta*180/(pi);
                    app.psiEditField.Value = psi*180/(pi);
                    app.pEditField.Value = xsave(end, 11)*180/(pi);
                    app.qEditField.Value = xsave(end, 12)*180/(pi);
                    app.rEditField.Value = xsave(end, 13)*180/(pi);
                    

                    Kf = 6.11e-8;
                    hold(app.UIAxes,'on')
                    F=evalin('base','F1');
                    quadcopter_body.translation=[10*xsave(end, 2) 10*xsave(end, 3) 10*xsave(end, 1)];
                    quadcopter_body.rotation=[0 0 1 theta*180/(pi)];
                    quadcopter_body.rotation=[1 0 0 phi*180/(pi)];
                    quadcopter_body.rotation=[0 1 0 psi*180/(pi)];                
                    myworld.Blade1.rotation=[0 1 0 sqrt(F(1)/Kf)*time];
                    myworld.Blade2.rotation=[0 1 0 sqrt(F(2)/Kf)*time];
                    myworld.Blade3.rotation=[0 1 0 sqrt(F(3)/Kf)*time];
                    myworld.Blade4.rotation=[0 1 0 sqrt(F(4)/Kf)*time];

                    QP{app.qn}.UpdateQuadPlot(x{app.qn}, [desired_state.pos; desired_state.vel], tt);

                    hold(app.UIAxes,'off')
                    
                    
                    time = time + cstep; % Update simulation time
                    % Check termination criteria
                    if terminate_check(x, time, stop, pos_tol, vel_tol, time_tol,app.qn)
                        break
                    end
                end
            catch

            end
            try
                QP{app.qn}.TruncateHist();
                % Plot position for each quad
                h_pos{app.qn} = figure('Name', ['Quad ' num2str(app.qn) ' : position 3d']);
                
                plot_state(h_pos{app.qn}, QP{app.qn}.state_hist(1:3,:), QP{app.qn}.time_hist, 'pos', 'vic','3d');
                plot_state(h_pos{app.qn}, QP{app.qn}.state_des_hist(1:3,:), QP{app.qn}.time_hist, 'pos', 'des','3d');
                h_po{app.qn} = figure('Name', ['Quad ' num2str(app.qn) ' : position']);
                plot_state(h_po{app.qn}, QP{app.qn}.state_hist(1:3,:), QP{app.qn}.time_hist, 'pos', 'vic');
                plot_state(h_po{app.qn}, QP{app.qn}.state_des_hist(1:3,:), QP{app.qn}.time_hist, 'pos', 'des');
                % Plot velocity for each quad
                
                h_vel{app.qn} = figure('Name', ['Quad ' num2str(app.qn) ' : velocity']);
                
                plot_state(h_vel{app.qn}, QP{app.qn}.state_hist(4:6,:), QP{app.qn}.time_hist, 'vel', 'vic');
                plot_state(h_vel{app.qn}, QP{app.qn}.state_des_hist(4:6,:), QP{app.qn}.time_hist, 'vel', 'des');
            catch
            end


            try
                set(app.TrajectoryButtonGroup,'Visible','on')
            catch
                
            end
            app.StopButtonPushed();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                selectedButton = app.TrajectoryButtonGroup.SelectedObject;
                if selectedButton==app.CustomTrajectoryButton
                    set(app.CustomTrajectoryPanel,'Visible','on')
                    set(app.TrajectoryButtonGroup,'Visible','on')
                end
                app.UIAxes.cla;
                app.UIAxes.Title.String='Drone';
                app.qn=0;
                hold(app.UIAxes,'on')
                try
                    plot3(app.UIAxes,app.Data(:,1),app.Data(:,2),app.Data(:,3),'r*')
                catch
                end
                hold(app.UIAxes,'off')
                app.XEditField_2.Value = 0;
                app.YEditField_2.Value = 0;
                app.ZEditField_2.Value = 0;
                app.VxEditField.Value = 0;
                app.VyEditField.Value = 0;
                app.VzEditField.Value = 0;
                app.phiEditField.Value = 0;
                app.thetaEditField.Value = 0;
                app.psiEditField.Value = 0;
                app.pEditField.Value = 0;
                app.qEditField.Value = 0;
                app.rEditField.Value = 0;
                
            catch
            end
            
        end

        % Close request function: DsimulationUIFigure
        function DsimulationUIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrajectoryButtonGroup.SelectedObject;
            if selectedButton==app.CustomTrajectoryButton
                set(app.CustomTrajectoryPanel,'Visible','on');
                try
                    hold(app.UIAxes,'on')
                    plot3(app.UIAxes,app.Data(:,1),app.Data(:,2),app.Data(:,3),'r*')
                    hold(app.UIAxes,'off')
                catch
                end
            else
                set(app.CustomTrajectoryPanel,'Visible','off');
                app.UIAxes.cla;
                app.UIAxes.Title.String='Drone';
                app.qn=0;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addData = [app.XEditField.Value app.YEditField.Value app.ZEditField.Value ];
            app.Data(end+1,:)=addData;
            set(app.UITable,'Data', app.Data);
            hold(app.UIAxes,'on')
            plot3(app.UIAxes,addData(1),addData(2),addData(3),'r*')
            hold(app.UIAxes,'off')
        end

        % Value changed function: MassSlider
        function MassSliderValueChanged(app, event)
            value = app.MassSlider.Value;
            app.params.mass = value;
        end

        % Value changed function: GravitySlider
        function GravitySliderValueChanged(app, event)
            value = app.GravitySlider.Value;
            app.params.grav = value;
            
        end

        % Value changed function: FmaxSlider
        function FmaxSliderValueChanged(app, event)
            value = app.FmaxSlider.Value;
            app.params.maxF = value;
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = app.SpeedSlider.Value;
            app.params.Speed = value;
        end

        % Selection changed function: OptimizationButtonGroup
        function OptimizationButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptimizationButtonGroup.SelectedObject;
            if selectedButton == app.MinimumsnaptrajectoryoptimizationButton
                app.Optimization = 7;
            elseif selectedButton == app.MinimumaccelerationtrajectoryoptimizationButton
                app.Optimization = 3;
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.Data = [];
            set(app.UITable,'Data', app.Data);
            app.UIAxes.cla;
        end

        % Value changed function: AxEditField
        function AxEditFieldValueChanged(app, event)
            value = app.AxEditField.Value;
            app.params.Ax = value;
        end

        % Value changed function: AyEditField
        function AyEditFieldValueChanged(app, event)
            value = app.AyEditField.Value;
            app.params.Ay = value;
        end

        % Value changed function: AzEditField
        function AzEditFieldValueChanged(app, event)
            value = app.AzEditField.Value;
            app.params.Az = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DsimulationUIFigure = uifigure;
            app.DsimulationUIFigure.Name = '3D simulation';
            app.DsimulationUIFigure.CloseRequestFcn = createCallbackFcn(app, @DsimulationUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.DsimulationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimulateButton = uibutton(app.DsimulationUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TrajectoryButtonGroup = uibuttongroup(app.DsimulationUIFigure);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.TrajectoryButtonGroup.Title = 'Trajectory';
            app.StepButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.DiamondButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.DiamondButton.Text = 'Diamond';
            app.CircleButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CustomTrajectoryButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.CustomTrajectoryButton.Text = 'Custom Trajectory';
            app.StopButton = uibutton(app.DsimulationUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CustomTrajectoryPanel = uipanel(app.DsimulationUIFigure);
            app.CustomTrajectoryPanel.Title = 'Custom Trajectory';
            app.UITable = uitable(app.CustomTrajectoryPanel);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.AddButton = uibutton(app.CustomTrajectoryPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.XEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CustomTrajectoryPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CustomTrajectoryPanel, 'numeric');
            app.SpeedSliderLabel = uilabel(app.CustomTrajectoryPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.CustomTrajectoryPanel);
            app.SpeedSlider.Limits = [0 10];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 2;
            app.OptimizationButtonGroup = uibuttongroup(app.CustomTrajectoryPanel);
            app.OptimizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptimizationButtonGroupSelectionChanged, true);
            app.OptimizationButtonGroup.Title = 'Optimization';
            app.MinimumsnaptrajectoryoptimizationButton = uiradiobutton(app.OptimizationButtonGroup);
            app.MinimumsnaptrajectoryoptimizationButton.Text = 'Minimum snap trajectory optimization';
            app.MinimumsnaptrajectoryoptimizationButton.Value = true;
            app.MinimumaccelerationtrajectoryoptimizationButton = uiradiobutton(app.OptimizationButtonGroup);
            app.MinimumaccelerationtrajectoryoptimizationButton.Text = 'Minimum acceleration trajectory optimization';
            app.ClearDataButton = uibutton(app.CustomTrajectoryPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.MassSliderLabel = uilabel(app.DsimulationUIFigure);
            app.MassSliderLabel.Text = 'Mass';
            app.MassSlider = uislider(app.DsimulationUIFigure);
            app.MassSlider.Limits = [0 2];
            app.MassSlider.ValueChangedFcn = createCallbackFcn(app, @MassSliderValueChanged, true);
            app.MassSlider.Value = 0.3;
            app.GravitySliderLabel = uilabel(app.DsimulationUIFigure);
            app.GravitySliderLabel.Text = 'Gravity';
            app.GravitySlider = uislider(app.DsimulationUIFigure);
            app.GravitySlider.Limits = [5 10];
            app.GravitySlider.ValueChangedFcn = createCallbackFcn(app, @GravitySliderValueChanged, true);
            app.GravitySlider.Value = 9.8;
            app.FmaxSliderLabel = uilabel(app.DsimulationUIFigure);
            app.FmaxSliderLabel.Text = 'Fmax';
            app.FmaxSlider = uislider(app.DsimulationUIFigure);
            app.FmaxSlider.Limits = [0 10];
            app.FmaxSlider.ValueChangedFcn = createCallbackFcn(app, @FmaxSliderValueChanged, true);
            app.FmaxSlider.Value = 8;
            app.StatePanel = uipanel(app.DsimulationUIFigure);
            app.StatePanel.Title = 'State';
            app.XEditField_2Label = uilabel(app.StatePanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.StatePanel, 'numeric');
            app.YEditField_2Label = uilabel(app.StatePanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.StatePanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.StatePanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.StatePanel, 'numeric');
            app.VxEditFieldLabel = uilabel(app.StatePanel);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.StatePanel, 'numeric');
            app.VyEditFieldLabel = uilabel(app.StatePanel);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.StatePanel, 'numeric');
            app.VzEditFieldLabel = uilabel(app.StatePanel);
            app.VzEditFieldLabel.Text = 'Vz';
            app.VzEditField = uieditfield(app.StatePanel, 'numeric');
            app.phiEditFieldLabel = uilabel(app.StatePanel);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.StatePanel, 'numeric');
            app.thetaEditFieldLabel = uilabel(app.StatePanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.StatePanel, 'numeric');
            app.psiEditFieldLabel = uilabel(app.StatePanel);
            app.psiEditFieldLabel.Text = 'psi';
            app.psiEditField = uieditfield(app.StatePanel, 'numeric');
            app.pEditFieldLabel = uilabel(app.StatePanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.StatePanel, 'numeric');
            app.qEditFieldLabel = uilabel(app.StatePanel);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.StatePanel, 'numeric');
            app.rEditFieldLabel = uilabel(app.StatePanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.StatePanel, 'numeric');
            app.SimulateVRButton = uibutton(app.DsimulationUIFigure, 'push');
            app.SimulateVRButton.Text = 'Simulate VR';
            app.AerodynamicaleffectsPanel = uipanel(app.DsimulationUIFigure);
            app.AerodynamicaleffectsPanel.Title = 'Aerodynamical effects';
            app.AxEditFieldLabel = uilabel(app.AerodynamicaleffectsPanel);
            app.AxEditFieldLabel.Text = 'Ax';
            app.AxEditField = uieditfield(app.AerodynamicaleffectsPanel, 'numeric');
            app.AxEditField.ValueChangedFcn = createCallbackFcn(app, @AxEditFieldValueChanged, true);
            app.AxEditField.Value = 0.25;
            app.AyEditFieldLabel = uilabel(app.AerodynamicaleffectsPanel);
            app.AyEditFieldLabel.Text = 'Ay';
            app.AyEditField = uieditfield(app.AerodynamicaleffectsPanel, 'numeric');
            app.AyEditField.ValueChangedFcn = createCallbackFcn(app, @AyEditFieldValueChanged, true);
            app.AyEditField.Value = 0.25;
            app.AzEditFieldLabel = uilabel(app.AerodynamicaleffectsPanel);
            app.AzEditFieldLabel.Text = 'Az';
            app.AzEditField = uieditfield(app.AerodynamicaleffectsPanel, 'numeric');
            app.AzEditField.ValueChangedFcn = createCallbackFcn(app, @AzEditFieldValueChanged, true);
            app.AzEditField.Value = 0.25;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Simulate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DsimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DsimulationUIFigure)
        end
    end
end